classdef getting_key_int < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KeyGeneraatorUIFigure        matlab.ui.Figure
        GnrerclsButton               matlab.ui.control.Button
        ClsTextArea                  matlab.ui.control.TextArea
        ParamtresdentresPanel        matlab.ui.container.Panel
        NombredeclEditFieldLabel     matlab.ui.control.Label
        NombredeclEditField          matlab.ui.control.NumericEditField
        LongueurdebitsDropDownLabel  matlab.ui.control.Label
        LongueurdebitsDropDown       matlab.ui.control.DropDown
        ModeButtonGroup              matlab.ui.container.ButtonGroup
        NormalButton                 matlab.ui.control.RadioButton
        InversButton                 matlab.ui.control.RadioButton
        ParcoursDropDownLabel        matlab.ui.control.Label
        ParcoursDropDown             matlab.ui.control.DropDown
        ClenentreEditFieldLabel      matlab.ui.control.Label
        ClenentreEditField           matlab.ui.control.EditField
        ClsgnresLabel                matlab.ui.control.Label
        GnrationdeclsLabel           matlab.ui.control.Label
    end


    methods (Access = private)
    
        function generateKeys(app)
            % Get user inputs
            nb_key = app.NombredeclEditField.Value;
            length_key = str2double(app.LongueurdebitsDropDown.Value);
            mode = strcmp(app.ModeButtonGroup.SelectedObject.Text, 'Inverse');
            choices = {'Peano', 'Peano désordonné', 'Moore', 'Moore désordonné', 'Géométrie de Hilbert', 'Géométrie de Hilbert désordonné', 'Morton', 'Morton désordonné', 'Lebesgue', 'Lebesgue désordonné', 'Aztec', 'Aztec désordonné', 'Grammaire de Hilbert', 'Grammaire de Hilbert désordonné', 'Tenseur Récursif de Hilbert', 'Tenseur Récursif de Hilbert désordonné', 'Tenseur Itératif de Hilbert', 'Tenseur Itératif de Hilbert désordonné', 'Hilbert Quantique', 'Hilbert Quantique désordonné'};
            choice = find(strcmp(app.ParcoursDropDown.Value, choices)); % Convert choice to index
            message = app.ClenentreEditField.Value;
            
            %Le message ne doit pas être vide
            if isempty(message)
                uialert(app.KeyGeneraatorUIFigure, 'La clé en entree ne doit pas être vide','Erreur');
                return;
            end
            
            % Initialize last_hash (using dummy Shake256)
            last_hash = Shake256(message, length_key / 4);
            
            % Generate the keys
            generated_keys = '';
            for i = 1:nb_key
                next_hash = getting_key_with_parm(mode, choice, last_hash, length_key);
                % Format the next_hash with spaces
                formatted_key = formatHexString(next_hash, 32); % 8 characters for 32 bits
                generated_keys = [generated_keys, formatted_key,newline];
                last_hash = next_hash;

            end
            
            % Display the keys
            app.ClsTextArea.Value = generated_keys;
        end
              

        % Button pushed function: GenerateKeysButton
        function onGenerateKeysButtonPushed(app, event)
            generateKeys(app);
        end

        
    end


    methods (Access = private)

        % Button pushed function: GnrerclsButton
        function GnrerclsButtonPushed(app, event)
            generateKeys(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KeyGeneraatorUIFigure = uifigure;
            app.KeyGeneraatorUIFigure.Name = 'Key Generaator';
            app.GnrerclsButton = uibutton(app.KeyGeneraatorUIFigure, 'push');
            app.GnrerclsButton.ButtonPushedFcn = createCallbackFcn(app, @GnrerclsButtonPushed, true);
            app.GnrerclsButton.Text = 'Générer clés';
            app.ClsTextArea = uitextarea(app.KeyGeneraatorUIFigure);
            app.ParamtresdentresPanel = uipanel(app.KeyGeneraatorUIFigure);
            app.ParamtresdentresPanel.Title = 'Paramètres d''entrées';
            app.NombredeclEditFieldLabel = uilabel(app.ParamtresdentresPanel);
            app.NombredeclEditFieldLabel.Text = 'Nombre de clé';
            app.NombredeclEditField = uieditfield(app.ParamtresdentresPanel, 'numeric');
            app.NombredeclEditField.Value = 1;
            app.LongueurdebitsDropDownLabel = uilabel(app.ParamtresdentresPanel);
            app.LongueurdebitsDropDownLabel.Text = 'Longueur de bits';
            app.LongueurdebitsDropDown = uidropdown(app.ParamtresdentresPanel);
            app.LongueurdebitsDropDown.Items = {'256', '512', '1024', '2048', '4096'};
            app.LongueurdebitsDropDown.Value = '256';
            app.ModeButtonGroup = uibuttongroup(app.ParamtresdentresPanel);
            app.ModeButtonGroup.Title = 'Mode';
            app.NormalButton = uiradiobutton(app.ModeButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.InversButton = uiradiobutton(app.ModeButtonGroup);
            app.InversButton.Text = 'Inversé';
            app.ParcoursDropDownLabel = uilabel(app.ParamtresdentresPanel);
            app.ParcoursDropDownLabel.Text = 'Parcours';
            app.ParcoursDropDown = uidropdown(app.ParamtresdentresPanel);
            app.ParcoursDropDown.Items = {'Peano', 'Peano désordonné', 'Moore', 'Moore désordonné', 'Géométrie de Hilbert', 'Géométrie de Hilbert désordonné', 'Morton', 'Morton désordonné', 'Lebesgue', 'Lebesgue désordonné', 'Aztec', 'Aztec désordonné', 'Grammaire de Hilbert', 'Grammaire de Hilbert désordonné', 'Tenseur Récursif de Hilbert', 'Tenseur Récursif de Hilbert désordonné', 'Tenseur Itératif de Hilbert', 'Tenseur Itératif de Hilbert désordonné', 'Hilbert Quantique', 'Hilbert Quantique désordonné'};
            app.ParcoursDropDown.Value = 'Peano';
            app.ClenentreEditFieldLabel = uilabel(app.ParamtresdentresPanel);
            app.ClenentreEditFieldLabel.Text = 'Clé en entrée';
            app.ClenentreEditField = uieditfield(app.ParamtresdentresPanel, 'text');
            app.ClsgnresLabel = uilabel(app.KeyGeneraatorUIFigure);
            app.ClsgnresLabel.Text = 'Clés générées:';
            app.GnrationdeclsLabel = uilabel(app.KeyGeneraatorUIFigure);
            app.GnrationdeclsLabel.Text = 'Génération de clés';
        end
    end

    methods (Access = public)

        % Construct app
        function app = getting_key_int

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KeyGeneraatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KeyGeneraatorUIFigure)
        end
    end
end